classdef plate_radiation_v0 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        MEditFieldLabel                 matlab.ui.control.Label
        MEditField                      matlab.ui.control.NumericEditField
        NEditFieldLabel                 matlab.ui.control.Label
        NEditField                      matlab.ui.control.NumericEditField
        Label                           matlab.ui.control.Label
        LargeurmSpinnerLabel            matlab.ui.control.Label
        LargeurmSpinner                 matlab.ui.control.Spinner
        LongueurmSpinnerLabel           matlab.ui.control.Label
        LongueurmSpinner                matlab.ui.control.Spinner
        EpaisseurmSpinnerLabel          matlab.ui.control.Label
        EpaisseurmSpinner               matlab.ui.control.Spinner
        MasseVolumiquekgm3SliderLabel   matlab.ui.control.Label
        MasseVolumiquekgm3Slider        matlab.ui.control.Slider
        ModuleYoungGPaSliderLabel       matlab.ui.control.Label
        ModuleYoungGPaSlider            matlab.ui.control.Slider
        CoefficientdePoissonSliderLabel  matlab.ui.control.Label
        CoefficientdePoissonSlider      matlab.ui.control.Slider
        TextArea_3                      matlab.ui.control.TextArea
        DimensionsdelaplaqueLabel       matlab.ui.control.Label
        OrdredesmodesLabel              matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        PropritsdelaplaqueLabel         matlab.ui.control.Label
        RightPanel                      matlab.ui.container.Panel
        TabGroup                        matlab.ui.container.TabGroup
        Thorie1Tab                      matlab.ui.container.Tab
        EditField_98                    matlab.ui.control.EditField
        Image                           matlab.ui.control.Image
        Image2                          matlab.ui.control.Image
        Image3                          matlab.ui.control.Image
        Image4                          matlab.ui.control.Image
        Thorie2Tab                      matlab.ui.container.Tab
        Image5                          matlab.ui.control.Image
        EditField_99                    matlab.ui.control.EditField
        EditField_100                   matlab.ui.control.EditField
        ModesdeplaqueTab                matlab.ui.container.Tab
        UIAxes                          matlab.ui.control.UIAxes
        CalculdesdformesmodalesButton   matlab.ui.control.Button
        Tableaudesmodes                 matlab.ui.container.Tab
        EditField                       matlab.ui.control.NumericEditField
        EditField_2                     matlab.ui.control.NumericEditField
        EditField_3                     matlab.ui.control.NumericEditField
        EditField_4                     matlab.ui.control.NumericEditField
        EditField_5                     matlab.ui.control.NumericEditField
        EditField_6                     matlab.ui.control.NumericEditField
        EditField_7                     matlab.ui.control.NumericEditField
        EditField_8                     matlab.ui.control.NumericEditField
        EditField_11                    matlab.ui.control.NumericEditField
        EditField_12                    matlab.ui.control.NumericEditField
        EditField_13                    matlab.ui.control.NumericEditField
        EditField_14                    matlab.ui.control.NumericEditField
        EditField_15                    matlab.ui.control.NumericEditField
        EditField_16                    matlab.ui.control.NumericEditField
        EditField_17                    matlab.ui.control.NumericEditField
        EditField_18                    matlab.ui.control.NumericEditField
        EditField_21                    matlab.ui.control.NumericEditField
        EditField_22                    matlab.ui.control.NumericEditField
        EditField_23                    matlab.ui.control.NumericEditField
        EditField_24                    matlab.ui.control.NumericEditField
        EditField_25                    matlab.ui.control.NumericEditField
        EditField_26                    matlab.ui.control.NumericEditField
        EditField_27                    matlab.ui.control.NumericEditField
        EditField_28                    matlab.ui.control.NumericEditField
        EditField_31                    matlab.ui.control.NumericEditField
        EditField_32                    matlab.ui.control.NumericEditField
        EditField_33                    matlab.ui.control.NumericEditField
        EditField_34                    matlab.ui.control.NumericEditField
        EditField_35                    matlab.ui.control.NumericEditField
        EditField_36                    matlab.ui.control.NumericEditField
        EditField_37                    matlab.ui.control.NumericEditField
        EditField_38                    matlab.ui.control.NumericEditField
        EditField_41                    matlab.ui.control.NumericEditField
        EditField_42                    matlab.ui.control.NumericEditField
        EditField_43                    matlab.ui.control.NumericEditField
        EditField_44                    matlab.ui.control.NumericEditField
        EditField_45                    matlab.ui.control.NumericEditField
        EditField_46                    matlab.ui.control.NumericEditField
        EditField_47                    matlab.ui.control.NumericEditField
        EditField_48                    matlab.ui.control.NumericEditField
        EditField_51                    matlab.ui.control.NumericEditField
        EditField_52                    matlab.ui.control.NumericEditField
        EditField_53                    matlab.ui.control.NumericEditField
        EditField_54                    matlab.ui.control.NumericEditField
        EditField_55                    matlab.ui.control.NumericEditField
        EditField_56                    matlab.ui.control.NumericEditField
        EditField_57                    matlab.ui.control.NumericEditField
        EditField_58                    matlab.ui.control.NumericEditField
        EditField_61                    matlab.ui.control.NumericEditField
        EditField_62                    matlab.ui.control.NumericEditField
        EditField_63                    matlab.ui.control.NumericEditField
        EditField_64                    matlab.ui.control.NumericEditField
        EditField_65                    matlab.ui.control.NumericEditField
        EditField_66                    matlab.ui.control.NumericEditField
        EditField_67                    matlab.ui.control.NumericEditField
        EditField_68                    matlab.ui.control.NumericEditField
        EditField_71                    matlab.ui.control.NumericEditField
        EditField_72                    matlab.ui.control.NumericEditField
        EditField_73                    matlab.ui.control.NumericEditField
        EditField_74                    matlab.ui.control.NumericEditField
        EditField_75                    matlab.ui.control.NumericEditField
        EditField_76                    matlab.ui.control.NumericEditField
        EditField_77                    matlab.ui.control.NumericEditField
        EditField_78                    matlab.ui.control.NumericEditField
        EditField_79                    matlab.ui.control.NumericEditField
        EditField_80                    matlab.ui.control.NumericEditField
        EditField_81                    matlab.ui.control.NumericEditField
        EditField_82                    matlab.ui.control.NumericEditField
        EditField_83                    matlab.ui.control.NumericEditField
        EditField_84                    matlab.ui.control.NumericEditField
        EditField_85                    matlab.ui.control.NumericEditField
        EditField_86                    matlab.ui.control.NumericEditField
        EditField_87                    matlab.ui.control.NumericEditField
        EditField_88                    matlab.ui.control.NumericEditField
        EditField_89                    matlab.ui.control.NumericEditField
        EditField_90                    matlab.ui.control.NumericEditField
        EditField_91                    matlab.ui.control.NumericEditField
        EditField_92                    matlab.ui.control.NumericEditField
        EditField_93                    matlab.ui.control.NumericEditField
        EditField_94                    matlab.ui.control.NumericEditField
        EditField_95                    matlab.ui.control.EditField
        EditField_96                    matlab.ui.control.EditField
        EditField_97                    matlab.ui.control.EditField
        CalculButton                    matlab.ui.control.Button
        EvaluationdurayonnementTab      matlab.ui.container.Tab
        FrquencedintrtHzEditFieldLabel  matlab.ui.control.Label
        FrquencedintrtHzEditField       matlab.ui.control.NumericEditField
        UIAxes2                         matlab.ui.control.UIAxes
        Calcul_SpectreButton            matlab.ui.control.Button
        Image6                          matlab.ui.control.Image
        FacteurderayonnementapprochTab  matlab.ui.container.Tab
        UIAxes3                         matlab.ui.control.UIAxes
        CalculButton_2                  matlab.ui.control.Button
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        Lx=140e-3;    % longueur [m]
        Ly=70e-3; % Largeur [m]
        Lz = 2e-3;     % Epaisseur [m] vérifier ok
        
        % --- Paramètres intrasèque de la plaque ---
        rho = 500;   % Masse volumique [kg/m3]
        E = 150e9;      % Module Young
        nu = 0.33;    % Coefficient de poisson
        
        % --- Fréquence d'excitation ---
        f = 1700;
        
        % --- Paramètres du milieu fluide ---
        rho_air = 1.22; % Masse volumique du fluide, ici l'air [kg/m^3]
        c = 340; % Vitesse du son dans l'air [m/s²]
        
        % --- Force injectée ---
        Force = 1; % [N]
        %         xf0 = Lx/2; % [m]
        %         yf0 = Ly/2; % [m]
        
        % --- Ordre des modes de vibration
        m_v = 1;
        n_v = 1;
        
        phi_mn=0;
        
        % Theta
        theta=1:0.01:2*pi;
        
        % Phi
        phi=1:0.1:90; % Description
        
        
        ds=1e-3;
% plus besoin d'eux
%         x=0;
%         Nx=0;
%         y=0;
%         Ny=0;
%         z=0;
%         Nz=0;
        
        D=0;
        omega=0;
        omega_critique=0;
        lambda_critique=0;
        freq_critique=0;
        kf=30;
        k=0;
        lambda=0;
        kx=0;
        ky=0;
        omega_pq=0;
        omega_p=0;
        %term1=0;
        %term2=0;
        phi_chapeau=0;
        phi_chapeau2=0;
        frequence_pq=0;
        
        omega_pq3=0;
        f_pq=0;
        m_vv=0;
        n_vv=0;
        
        f_p=0;
        alpha=0;
        sigma=0;
        r=0;
        
    end
    
    methods (Access = private)
        
        % Fonction supprimée. Il est plus simple de calculer directement le
        % Nx et le vecteur x au moment ou on en a besoin (ça n'arrive
        % qu'une seule fois, quand il faut calculer les déformées). Comme
        % ça on allège les attributs (6 en moins, c'est pas rien !)
%         function discretisation(app)
%             app.x=0:app.ds:app.Lx;
%             app.Nx=length(app.x);
%             
%             app.y=0:app.ds:app.Ly;
%             app.Ny=length(app.y);
%             
%             app.z=0:app.ds:app.Lz;
%             app.Nz=length(app.z);
%         end
        
        function rig_flexion(app)
            app.D = app.E*app.Lz^3/(12*(1-app.nu^2));
        end
        
        function omega_excitation(app)
            app.omega = app.f*2*pi; % Omega excitation
        end
        
        function frequences_critiques(app)
            app.omega_critique = app.c^2*sqrt((app.rho*app.Lz)/app.D);
            app.lambda_critique=app.f/app.omega_critique;
            app.freq_critique=(app.c^2/(2*pi))*sqrt(app.rho*app.Lz/app.D);
        end
        
        function relation_dispertion(app)
            app.kf = app.omega*sqrt(app.rho*app.Lz/app.D); % Il s'agit de kf^2 = kx^2+ky^2
        end
        
        function nombre_onde(app)
            app.k = app.omega/app.c;
        end
        
        function longueur_onde(app)
            app.lambda = app.c/app.f;
        end
        
        function fkx(app)
            %             app.kx=0:1:100;
            app.kx= 0:0.1*pi/app.Lx:100;
        end
        function fky(app)
            %             app.ky=0:1:100;
            app.ky=app.kx;
        end
        %       Calcul des déformées propres de la plaque
        function deformees_propres(app)
           
            
            % discretisation des X
            x = 0 : app.ds : app.Lx;
            %Nx = length(x);
            
            % discretisation des y
            y = 0 : app.ds : app.Ly;
            %Ny = length(y);
   
            % calcul de la grille selon X et Y (matrice de dim = Lx*Ly)
            [X,Y] = meshgrid(x,y);
            
            % calcul de la déformée (sans boucle héhé !)
            app.phi_mn = sin( app.m_v * pi * X / app.Lx) .* ...
                        sin( app.n_v * pi * Y/app.Ly);
            
            
%             for ii = 1 : Nx
%                 for jj = 1 : Ny
%                     app.phi_mn(ii,jj) = sin((app.m_v*pi*x(ii))/app.Lx) * ...
%                         sin((app.n_v*pi*app.y(jj))/app.Ly);
%                 end
%             end
           % clear ii;clear jj;
        end
        %       Calcul des fréquences propres des modes m,n
        function freq_propre(app)
            app.omega_pq=sqrt(app.D/(app.rho*app.Lz))*((app.m_v*pi/app.Lx)^2+(app.n_v*pi/app.Ly)^2);
            app.omega_p=sqrt(app.D/(app.rho*app.Lz))*(app.m_v*pi/app.Lx)^2;


            %             app.omega_pq3=sqrt(app.D/(app.rho*app.Lz))*((app.m_vv*pi/app.Lx)^2+(app.n_v*pi/app.Ly)^2);
%             app.m_vv=1:1:8;
%             app.n_vv=app.m_vv;
%             for ii=1:length(app.m_vv)
%                 for jj=1:length(app.n_vv)
%                     app.omega_pq3(ii,jj)=sqrt(app.D/(app.rho*app.Lz))*((app.m_vv(ii)*pi/app.Lx)^2+(app.n_vv(jj)*pi/app.Ly)^2);
%                 end
%             end
            
            % autre approche avec meshgrid 
            app.m_vv = 1:8;
            app.n_vv = app.m_vv;
            [M,N] = meshgrid(app.m_vv,app.n_vv);
            app.omega_pq3 = sqrt(app.D/(app.rho*app.Lz))*((M*pi/app.Lx).^2+(N*pi/app.Ly).^2);
            app.f_pq=app.omega_pq3./(2*pi);
        end


        %      Calcul du spectre du nombre d'onde de la déformé du mode M
        
        % Update : on intègre avec trapz() en créant un vecteur discret sur
        % x et y. Les term1 et term2 ne sont plus membres de la classe pour
        % alléger.
        function spectre_nb_onde(app)

            nb_kx = length(app.kx);
            nb_ky = length(app.ky);            
            term1 = zeros(nb_kx,nb_ky);
            term2 = zeros(nb_kx,nb_ky);
            
            step = 1e-4;
            x_vec = -app.Lx : step : app.Lx;
            y_vec = -app.Ly : step : app.Ly;
            
            for ii=1:length(app.kx)
                for jj=1:length(app.ky)
%                     fun1= @(x_) sin(app.m_v*pi*x_).*exp(1i*(app.kx(ii)*x_));                    
%                     term1(ii,jj)=integral(fun1,-app.Lx,app.Lx);                  
%                     fun2= @(y_) sin(app.n_v*pi*y_).*exp(1i*(app.ky(jj)*y_));
%                     term2(ii,jj)=integral(fun2,-app.Ly,app.Ly);
                                        
                    sin_x = sin(app.m_v*pi*x_vec).*exp(1i*(app.kx(ii)*x_vec));
                    sin_y = sin(app.n_v*pi*y_vec).*exp(1i*(app.ky(jj)*y_vec));
                    
                    term1(ii,jj) = trapz(sin_x);
                    term2(ii,jj) = trapz(sin_y);
                    
                end
            end
            app.phi_chapeau=term1*term2;
            app.phi_chapeau2=abs(app.phi_chapeau).^2;
        end
        
        
        function ray_approch(app)
            app.f_p=100:0.1:5000;
            app.r=app.Lx/app.Ly;    
            for ii=1:length (app.f_p)
                app.alpha(ii)=sqrt(app.f_p(ii)/app.freq_critique);
                g1=  (4/pi^4)*((1-2*app.alpha(ii)^2)/(app.alpha(ii)*sqrt(1-app.alpha(ii)^2)));
                g2=  (1/(4*pi^2))*(((1-app.alpha(ii)^2)*log((1+app.alpha(ii))/(1-app.alpha(ii)))+2*app.alpha(ii))/...
                    (1-app.alpha(ii)^2)^(3/2));
                if app.f_p(ii)<floor(app.freq_critique-0)
                    if app.f_p(ii)<0.5*app.freq_critique
                        app.sigma(ii)=2*app.r*(app.lambda_critique/app.Lx)^2*g1+2*(1+app.r)*(app.lambda_critique/app.Lx)...
                            *g2;
                    else
                        app.sigma(ii)=2*(1+app.r)*(app.lambda_critique/app.Lx)*g2;
                    end
%                         elseif app.f_p(ii)>=floor(app.freq_critique-10) && app.f_p(ii)<=floor(app.freq_critique+10)
%                             app.sigma(ii) = sqrt(app.Lx/app.lambda_critique)*(1+1/sqrt(app.r));
                else
                    app.sigma(ii)=(1-app.freq_critique/app.f_p(ii))^(-1/2);
                end
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: FrquencedintrtHzEditField
        function FrquencedintrtHzEditFieldValueChanged(app, event)
            app.f = app.FrquencedintrtHzEditField.Value;
            
        end

        % Button pushed function: CalculButton, CalculButton_2, 
        % CalculdesdformesmodalesButton
        function CalculdesdformesmodalesButtonPushed(app, event)
            fig = uifigure;
            d = uiprogressdlg(fig,'Title','Computing in progress...',...
                'Indeterminate','on');
            pause(.5)
            %             clc;
            %             app.BusyLabel.Text= 'Busy';
            FrquencedintrtHzEditFieldValueChanged(app, event);
            MEditFieldValueChanged(app,event);
            NEditFieldValueChanged(app,event);
            LargeurmSpinnerValueChanged(app,event);
            LongueurmSpinnerValueChanged(app,event);
            EpaisseurmSpinnerValueChanged(app,event);
            MasseVolumiquekgm3SliderValueChanged(app,event);
            ModuleYoungGPaSliderValueChanged(app,event);
            CoefficientdePoissonSliderValueChanged(app,event);
            %discretisation(app);
            rig_flexion(app);
            omega_excitation(app);
            frequences_critiques(app);
            relation_dispertion(app);
            nombre_onde(app);
            longueur_onde(app);
            fkx(app);
            fky(app);
            deformees_propres(app);
            freq_propre(app);
            ray_approch(app)
            %             spectre_nb_onde(app);
            pause(.5)
            close(d)
            close(fig)
            app.EditField.Value=app.f_pq(1,1);
            app.EditField_2.Value=app.f_pq(1,2);
            app.EditField_3.Value=app.f_pq(1,3);
            app.EditField_4.Value=app.f_pq(1,4);
            app.EditField_5.Value=app.f_pq(1,5);
            app.EditField_6.Value=app.f_pq(1,6);
            app.EditField_7.Value=app.f_pq(1,7);
            app.EditField_8.Value=app.f_pq(1,8);
            app.EditField_11.Value=app.f_pq(2,1);
            app.EditField_12.Value=app.f_pq(2,2);
            app.EditField_13.Value=app.f_pq(2,3);
            app.EditField_14.Value=app.f_pq(2,4);
            app.EditField_15.Value=app.f_pq(2,5);
            app.EditField_16.Value=app.f_pq(2,6);
            app.EditField_17.Value=app.f_pq(2,7);
            app.EditField_18.Value=app.f_pq(2,8);
            app.EditField_21.Value=app.f_pq(3,1);
            app.EditField_22.Value=app.f_pq(3,2);
            app.EditField_23.Value=app.f_pq(3,3);
            app.EditField_24.Value=app.f_pq(3,4);
            app.EditField_25.Value=app.f_pq(3,5);
            app.EditField_26.Value=app.f_pq(3,6);
            app.EditField_27.Value=app.f_pq(3,7);
            app.EditField_28.Value=app.f_pq(3,8);
            app.EditField_31.Value=app.f_pq(4,1);
            app.EditField_32.Value=app.f_pq(4,2);
            app.EditField_33.Value=app.f_pq(4,3);
            app.EditField_34.Value=app.f_pq(4,4);
            app.EditField_35.Value=app.f_pq(4,5);
            app.EditField_36.Value=app.f_pq(4,6);
            app.EditField_37.Value=app.f_pq(4,7);
            app.EditField_38.Value=app.f_pq(4,8);
            app.EditField_41.Value=app.f_pq(5,1);
            app.EditField_42.Value=app.f_pq(5,2);
            app.EditField_43.Value=app.f_pq(5,3);
            app.EditField_44.Value=app.f_pq(5,4);
            app.EditField_45.Value=app.f_pq(5,5);
            app.EditField_46.Value=app.f_pq(5,6);
            app.EditField_47.Value=app.f_pq(5,7);
            app.EditField_48.Value=app.f_pq(5,8);
            app.EditField_51.Value=app.f_pq(6,1);
            app.EditField_52.Value=app.f_pq(6,2);
            app.EditField_53.Value=app.f_pq(6,3);
            app.EditField_54.Value=app.f_pq(6,4);
            app.EditField_55.Value=app.f_pq(6,5);
            app.EditField_56.Value=app.f_pq(6,6);
            app.EditField_57.Value=app.f_pq(6,7);
            app.EditField_58.Value=app.f_pq(6,8);
            app.EditField_61.Value=app.f_pq(7,1);
            app.EditField_62.Value=app.f_pq(7,2);
            app.EditField_63.Value=app.f_pq(7,3);
            app.EditField_64.Value=app.f_pq(7,4);
            app.EditField_65.Value=app.f_pq(7,5);
            app.EditField_66.Value=app.f_pq(7,6);
            app.EditField_67.Value=app.f_pq(7,7);
            app.EditField_68.Value=app.f_pq(7,8);
            app.EditField_71.Value=app.f_pq(8,1);
            app.EditField_72.Value=app.f_pq(8,2);
            app.EditField_73.Value=app.f_pq(8,3);
            app.EditField_74.Value=app.f_pq(8,4);
            app.EditField_75.Value=app.f_pq(8,5);
            app.EditField_76.Value=app.f_pq(8,6);
            app.EditField_77.Value=app.f_pq(8,7);
            app.EditField_78.Value=app.f_pq(8,8);
            %             app.EditField_81.Value=app.f_pq(1,1);
            %             app.EditField_82.Value=app.f_pq(1,1);
            %             app.EditField_83.Value=app.f_pq(1,1);
            %             app.EditField_84.Value=app.f_pq(1,1);
            %             app.EditField_85.Value=app.f_pq(1,1);
            %             app.EditField_86.Value=app.f_pq(1,1);
            %             app.EditField_87.Value=app.f_pq(1,1);
            %             app.EditField_88.Value=app.f_pq(1,1);
            %             app.EditField_91.Value=app.f_pq(1,1);
            %             app.EditField_92.Value=app.f_pq(1,1);
            %             app.EditField_93.Value=app.f_pq(1,1);
            %             app.EditField_94.Value=app.f_pq(1,1);
            %             app.EditField_95.Value=app.f_pq(1,1);
            %             app.EditField_96.Value=app.f_pq(1,1);
            %             app.EditField_97.Value=app.f_pq(1,1);
            %             app.EditField_98.Value=app.f_pq(1,1);
            
            x_ = 0 : app.ds : app.Lx;
            y_ = 0 : app.ds : app.Ly;
            [X,Y] = meshgrid(x_,y_);
            surf(app.UIAxes,Y,X,app.phi_mn);
            xlabel(app.UIAxes,'X [m]');
            ylabel(app.UIAxes,'Y [m]');
            shading(app.UIAxes,'interp');
            title(app.UIAxes,'Mode de plaque');
            
%             app.sigma=abs(app.sigma);
            plot(app.UIAxes3,app.f_p,app.sigma,'Color','k','LineWidth',2);
            xlabel(app.UIAxes,'Frequency [Hz]','FontSize',14);
            ylabel(app.UIAxes,'Modal efficiency','FontSize',14');
            title(app.UIAxes3,'Facteur de rayonnement approché','FontSize',14);           


        end

        % Value changed function: MEditField
        function MEditFieldValueChanged(app, event)
            app.m_v = app.MEditField.Value;
            
        end

        % Value changed function: NEditField
        function NEditFieldValueChanged(app, event)
            app.n_v = app.NEditField.Value;
            
        end

        % Value changed function: LargeurmSpinner
        function LargeurmSpinnerValueChanged(app, event)
            app.Lx = app.LargeurmSpinner.Value;
            
        end

        % Value changed function: LongueurmSpinner
        function LongueurmSpinnerValueChanged(app, event)
            app.Ly = app.LongueurmSpinner.Value;
            
        end

        % Value changed function: EpaisseurmSpinner
        function EpaisseurmSpinnerValueChanged(app, event)
            app.Lz = app.EpaisseurmSpinner.Value;
            
        end

        % Value changed function: MasseVolumiquekgm3Slider
        function MasseVolumiquekgm3SliderValueChanged(app, event)
            app.rho = app.MasseVolumiquekgm3Slider.Value;
            
        end

        % Value changed function: ModuleYoungGPaSlider
        function ModuleYoungGPaSliderValueChanged(app, event)
            app.E = app.ModuleYoungGPaSlider.Value;
            
        end

        % Value changed function: CoefficientdePoissonSlider
        function CoefficientdePoissonSliderValueChanged(app, event)
            app.nu = app.CoefficientdePoissonSlider.Value;
            
        end

        % Button pushed function: Calcul_SpectreButton
        function Calcul_SpectreButtonPushed(app, event)
           
            fig = uifigure;
            d = uiprogressdlg(fig,'Title','Computing in progress...',...
                'Indeterminate','on');
            drawnow
            pause(.5)
            FrquencedintrtHzEditFieldValueChanged(app, event);
            MEditFieldValueChanged(app,event);
            NEditFieldValueChanged(app,event);
            LargeurmSpinnerValueChanged(app,event);
            LongueurmSpinnerValueChanged(app,event);
            EpaisseurmSpinnerValueChanged(app,event);
            MasseVolumiquekgm3SliderValueChanged(app,event);
            ModuleYoungGPaSliderValueChanged(app,event);
            CoefficientdePoissonSliderValueChanged(app,event);

            %discretisation(app);
            rig_flexion(app);
            omega_excitation(app);
            frequences_critiques(app);
            relation_dispertion(app);
            nombre_onde(app);
            longueur_onde(app);
            fkx(app);
            fky(app);
            deformees_propres(app);
            freq_propre(app);
            spectre_nb_onde(app);
            
            pause(.5)
            close(d)
            close(fig)
            
            contourf(app.UIAxes2,abs(app.phi_chapeau2))
            shading(app.UIAxes2,'interp')
            xlabel(app.UIAxes2,'K_x','FontSize',12);
            ylabel(app.UIAxes2,'K_y','FontSize',12);
            shading(app.UIAxes2,'interp')
            title(app.UIAxes2,['Spectre de nombre d"onde pour une plaque pour les modes M = ', num2str(app.m_v), ' & N = ', num2str(app.n_v) ' | Fréquence : ',num2str(app.f),' Hz'],'FontSize',12);
            hold (app.UIAxes2);
            plot(app.UIAxes2,app.k*cos(app.phi),app.k*sin(app.phi),'LineWidth',2,'Color','k');
            var_temp=sqrt(app.kf);
            plot(app.UIAxes2,var_temp*cos(app.phi),var_temp*sin(app.phi),'LineWidth',2,'Color','r');
            axis(app.UIAxes2,'equal')
            xlim(app.UIAxes2,[1 length(app.kx)])
            ylim(app.UIAxes2,[1 length(app.ky)])
            % legend


        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {546, 546};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {368, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.MEditFieldLabel = uilabel(app.LeftPanel);
            app.MEditFieldLabel.Text = 'M';
            app.MEditField = uieditfield(app.LeftPanel, 'numeric');
            app.MEditField.Limits = [1 50];
            app.MEditField.ValueChangedFcn = createCallbackFcn(app, @MEditFieldValueChanged, true);
            app.MEditField.Value = 1;
            app.NEditFieldLabel = uilabel(app.LeftPanel);
            app.NEditFieldLabel.Text = 'N';
            app.NEditField = uieditfield(app.LeftPanel, 'numeric');
            app.NEditField.Limits = [1 50];
            app.NEditField.ValueChangedFcn = createCallbackFcn(app, @NEditFieldValueChanged, true);
            app.NEditField.Value = 1;
            app.Label = uilabel(app.LeftPanel);
            app.Label.Text = '_____________________________________________________';
            app.LargeurmSpinnerLabel = uilabel(app.LeftPanel);
            app.LargeurmSpinnerLabel.Text = 'Largeur [m]';
            app.LargeurmSpinner = uispinner(app.LeftPanel);
            app.LargeurmSpinner.Step = 0.001;
            app.LargeurmSpinner.Limits = [0.001 10];
            app.LargeurmSpinner.ValueChangedFcn = createCallbackFcn(app, @LargeurmSpinnerValueChanged, true);
            app.LargeurmSpinner.Value = 0.122;
            app.LongueurmSpinnerLabel = uilabel(app.LeftPanel);
            app.LongueurmSpinnerLabel.Text = 'Longueur [m]';
            app.LongueurmSpinner = uispinner(app.LeftPanel);
            app.LongueurmSpinner.Step = 0.001;
            app.LongueurmSpinner.Limits = [0.001 10];
            app.LongueurmSpinner.ValueChangedFcn = createCallbackFcn(app, @LongueurmSpinnerValueChanged, true);
            app.LongueurmSpinner.Value = 0.076;
            app.EpaisseurmSpinnerLabel = uilabel(app.LeftPanel);
            app.EpaisseurmSpinnerLabel.Text = 'Epaisseur [m]';
            app.EpaisseurmSpinner = uispinner(app.LeftPanel);
            app.EpaisseurmSpinner.Step = 0.0001;
            app.EpaisseurmSpinner.Limits = [0 10];
            app.EpaisseurmSpinner.ValueChangedFcn = createCallbackFcn(app, @EpaisseurmSpinnerValueChanged, true);
            app.EpaisseurmSpinner.Value = 0.002;
            app.MasseVolumiquekgm3SliderLabel = uilabel(app.LeftPanel);
            app.MasseVolumiquekgm3SliderLabel.Text = 'Masse Volumique [kg/m3]';
            app.MasseVolumiquekgm3Slider = uislider(app.LeftPanel);
            app.MasseVolumiquekgm3Slider.Limits = [500 4000];
            app.MasseVolumiquekgm3Slider.ValueChangedFcn = createCallbackFcn(app, @MasseVolumiquekgm3SliderValueChanged, true);
            app.MasseVolumiquekgm3Slider.Value = 1470;
            app.ModuleYoungGPaSliderLabel = uilabel(app.LeftPanel);
            app.ModuleYoungGPaSliderLabel.Text = 'Module Young [GPa] ';
            app.ModuleYoungGPaSlider = uislider(app.LeftPanel);
            app.ModuleYoungGPaSlider.Limits = [1000000000 1000000000000];
            app.ModuleYoungGPaSlider.ValueChangedFcn = createCallbackFcn(app, @ModuleYoungGPaSliderValueChanged, true);
            app.ModuleYoungGPaSlider.Value = 9700000000;
            app.CoefficientdePoissonSliderLabel = uilabel(app.LeftPanel);
            app.CoefficientdePoissonSliderLabel.Text = 'Coefficient de Poisson';
            app.CoefficientdePoissonSlider = uislider(app.LeftPanel);
            app.CoefficientdePoissonSlider.Limits = [0 1];
            app.CoefficientdePoissonSlider.ValueChangedFcn = createCallbackFcn(app, @CoefficientdePoissonSliderValueChanged, true);
            app.CoefficientdePoissonSlider.Value = 0.33;
            app.TextArea_3 = uitextarea(app.LeftPanel);
            app.TextArea_3.Editable = 'off';
            app.TextArea_3.Value = {'Comportement Vibro-Acoustique d''une plaque'};
            app.DimensionsdelaplaqueLabel = uilabel(app.LeftPanel);
            app.DimensionsdelaplaqueLabel.Text = 'Dimensions de la plaque';
            app.OrdredesmodesLabel = uilabel(app.LeftPanel);
            app.OrdredesmodesLabel.Text = 'Ordre des modes';
            app.Label_2 = uilabel(app.LeftPanel);
            app.Label_2.Text = '_______________________________________________________';
            app.PropritsdelaplaqueLabel = uilabel(app.LeftPanel);
            app.PropritsdelaplaqueLabel.Text = 'Propriétés de la plaque';
            app.RightPanel = uipanel(app.GridLayout);
            app.TabGroup = uitabgroup(app.RightPanel);
            app.Thorie1Tab = uitab(app.TabGroup);
            app.Thorie1Tab.Title = 'Théorie (1)';
            app.EditField_98 = uieditfield(app.Thorie1Tab, 'text');
            app.EditField_98.Editable = 'off';
            app.EditField_98.Value = 'Référence : C.Pezerat " Part 3 - Vibroacoustique of finite plate "';
            app.Image = uiimage(app.Thorie1Tab);
            app.Image.ImageSource = '1.png';
            app.Image2 = uiimage(app.Thorie1Tab);
            app.Image2.ImageSource = '2.png';
            app.Image3 = uiimage(app.Thorie1Tab);
            app.Image3.ImageSource = '3.png';
            app.Image4 = uiimage(app.Thorie1Tab);
            app.Image4.ImageSource = '4.png';
            app.Thorie2Tab = uitab(app.TabGroup);
            app.Thorie2Tab.Title = 'Théorie (2)';
            app.Image5 = uiimage(app.Thorie2Tab);
            app.Image5.ImageSource = '5.png';
            app.EditField_99 = uieditfield(app.Thorie2Tab, 'text');
            app.EditField_99.Editable = 'off';
            app.EditField_99.Value = 'Exemple de courbes d''éfficacité modale pour le cas d''une plaque simple, simplement supportée sur ces 4 bords. ';
            app.EditField_100 = uieditfield(app.Thorie2Tab, 'text');
            app.EditField_100.Editable = 'off';
            app.EditField_100.Value = 'Ici la pulsation omega est normalisé entre 0 et 1.';
            app.ModesdeplaqueTab = uitab(app.TabGroup);
            app.ModesdeplaqueTab.Title = 'Modes de plaque';
            app.UIAxes = uiaxes(app.ModesdeplaqueTab);
            title(app.UIAxes, 'Mode M & N de plaque - Théorique')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.CalculdesdformesmodalesButton = uibutton(app.ModesdeplaqueTab, 'push');
            app.CalculdesdformesmodalesButton.ButtonPushedFcn = createCallbackFcn(app, @CalculdesdformesmodalesButtonPushed, true);
            app.CalculdesdformesmodalesButton.Text = 'Calcul des déformées modales';
            app.Tableaudesmodes = uitab(app.TabGroup);
            app.Tableaudesmodes.Title = 'Tableau des modes';
            app.EditField = uieditfield(app.Tableaudesmodes, 'numeric');
            app.EditField.ValueDisplayFormat = '%11.5g';
            app.EditField.Editable = 'off';
            app.EditField.Tooltip = {'Mode [1,1]'};
            app.EditField_2 = uieditfield(app.Tableaudesmodes, 'numeric');
            app.EditField_2.ValueDisplayFormat = '%11.5g';
            app.EditField_2.Editable = 'off';
            app.EditField_2.Tooltip = {'Mode [1,2]'};
            app.EditField_3 = uieditfield(app.Tableaudesmodes, 'numeric');
            app.EditField_3.ValueDisplayFormat = '%11.5g';
            app.EditField_3.Editable = 'off';
            app.EditField_3.Tooltip = {'Mode [1,3]'};
            app.EditField_4 = uieditfield(app.Tableaudesmodes, 'numeric');
            app.EditField_4.ValueDisplayFormat = '%11.5g';
            app.EditField_4.Editable = 'off';
            app.EditField_4.Tooltip = {'Mode [1,4]'};
            app.EditField_5 = uieditfield(app.Tableaudesmodes, 'numeric');
            app.EditField_5.ValueDisplayFormat = '%11.5g';
            app.EditField_5.Editable = 'off';
            app.EditField_5.Tooltip = {'Mode [1,5]'};
            app.EditField_6 = uieditfield(app.Tableaudesmodes, 'numeric');
            app.EditField_6.ValueDisplayFormat = '%11.5g';
            app.EditField_6.Editable = 'off';
            app.EditField_6.Tooltip = {'Mode [1,6]'};
            app.EditField_7 = uieditfield(app.Tableaudesmodes, 'numeric');
            app.EditField_7.ValueDisplayFormat = '%11.5g';
            app.EditField_7.Editable = 'off';
            app.EditField_7.Tooltip = {'Mode [1,7]'};
            app.EditField_8 = uieditfield(app.Tableaudesmodes, 'numeric');
            app.EditField_8.ValueDisplayFormat = '%11.5g';
            app.EditField_8.Editable = 'off';
            app.EditField_8.Tooltip = {'Mode [1,8]'};
            app.EditField_11 = uieditfield(app.Tableaudesmodes, 'numeric');
            app.EditField_11.ValueDisplayFormat = '%11.5g';
            app.EditField_11.Editable = 'off';
            app.EditField_11.Tooltip = {'Mode [2,1]'};
            app.EditField_12 = uieditfield(app.Tableaudesmodes, 'numeric');
            app.EditField_12.ValueDisplayFormat = '%11.5g';
            app.EditField_12.Editable = 'off';
            app.EditField_12.Tooltip = {'Mode [2,2]'};
            app.EditField_13 = uieditfield(app.Tableaudesmodes, 'numeric');
            app.EditField_13.ValueDisplayFormat = '%11.5g';
            app.EditField_13.Editable = 'off';
            app.EditField_13.Tooltip = {'Mode [2,3]'};
            app.EditField_14 = uieditfield(app.Tableaudesmodes, 'numeric');
            app.EditField_14.ValueDisplayFormat = '%11.5g';
            app.EditField_14.Editable = 'off';
            app.EditField_14.Tooltip = {'Mode [2,4]'};
            app.EditField_15 = uieditfield(app.Tableaudesmodes, 'numeric');
            app.EditField_15.ValueDisplayFormat = '%11.5g';
            app.EditField_15.Editable = 'off';
            app.EditField_15.Tooltip = {'Mode [2,5]'};
            app.EditField_16 = uieditfield(app.Tableaudesmodes, 'numeric');
            app.EditField_16.ValueDisplayFormat = '%11.5g';
            app.EditField_16.Editable = 'off';
            app.EditField_16.Tooltip = {'Mode [2,6]'};
            app.EditField_17 = uieditfield(app.Tableaudesmodes, 'numeric');
            app.EditField_17.ValueDisplayFormat = '%11.5g';
            app.EditField_17.Editable = 'off';
            app.EditField_17.Tooltip = {'Mode [2,7]'};
            app.EditField_18 = uieditfield(app.Tableaudesmodes, 'numeric');
            app.EditField_18.ValueDisplayFormat = '%11.5g';
            app.EditField_18.Editable = 'off';
            app.EditField_18.Tooltip = {'Mode [2,8]'};
            app.EditField_21 = uieditfield(app.Tableaudesmodes, 'numeric');
            app.EditField_21.ValueDisplayFormat = '%11.5g';
            app.EditField_21.Editable = 'off';
            app.EditField_21.Tooltip = {'Mode [3,1]'};
            app.EditField_22 = uieditfield(app.Tableaudesmodes, 'numeric');
            app.EditField_22.ValueDisplayFormat = '%11.5g';
            app.EditField_22.Editable = 'off';
            app.EditField_22.Tooltip = {'Mode [3,2]'};
            app.EditField_23 = uieditfield(app.Tableaudesmodes, 'numeric');
            app.EditField_23.ValueDisplayFormat = '%11.5g';
            app.EditField_23.Editable = 'off';
            app.EditField_23.Tooltip = {'Mode [3,3]'};
            app.EditField_24 = uieditfield(app.Tableaudesmodes, 'numeric');
            app.EditField_24.ValueDisplayFormat = '%11.5g';
            app.EditField_24.Editable = 'off';
            app.EditField_24.Tooltip = {'Mode [3,4]'};
            app.EditField_25 = uieditfield(app.Tableaudesmodes, 'numeric');
            app.EditField_25.ValueDisplayFormat = '%11.5g';
            app.EditField_25.Editable = 'off';
            app.EditField_25.Tooltip = {'Mode [3,5]'};
            app.EditField_26 = uieditfield(app.Tableaudesmodes, 'numeric');
            app.EditField_26.ValueDisplayFormat = '%11.5g';
            app.EditField_26.Editable = 'off';
            app.EditField_26.Tooltip = {'Mode [3,6]'};
            app.EditField_27 = uieditfield(app.Tableaudesmodes, 'numeric');
            app.EditField_27.ValueDisplayFormat = '%11.5g';
            app.EditField_27.Editable = 'off';
            app.EditField_27.Tooltip = {'Mode [3,7]'};
            app.EditField_28 = uieditfield(app.Tableaudesmodes, 'numeric');
            app.EditField_28.ValueDisplayFormat = '%11.5g';
            app.EditField_28.Editable = 'off';
            app.EditField_28.Tooltip = {'Mode [3,8]'};
            app.EditField_31 = uieditfield(app.Tableaudesmodes, 'numeric');
            app.EditField_31.ValueDisplayFormat = '%11.5g';
            app.EditField_31.Editable = 'off';
            app.EditField_31.Tooltip = {'Mode [4,1]'};
            app.EditField_32 = uieditfield(app.Tableaudesmodes, 'numeric');
            app.EditField_32.ValueDisplayFormat = '%11.5g';
            app.EditField_32.Editable = 'off';
            app.EditField_32.Tooltip = {'Mode [4,2]'};
            app.EditField_33 = uieditfield(app.Tableaudesmodes, 'numeric');
            app.EditField_33.ValueDisplayFormat = '%11.5g';
            app.EditField_33.Editable = 'off';
            app.EditField_33.Tooltip = {'Mode [4,3]'};
            app.EditField_34 = uieditfield(app.Tableaudesmodes, 'numeric');
            app.EditField_34.ValueDisplayFormat = '%11.5g';
            app.EditField_34.Editable = 'off';
            app.EditField_34.Tooltip = {'Mode [4,4]'};
            app.EditField_35 = uieditfield(app.Tableaudesmodes, 'numeric');
            app.EditField_35.ValueDisplayFormat = '%11.5g';
            app.EditField_35.Editable = 'off';
            app.EditField_35.Tooltip = {'Mode [1,1]'};
            app.EditField_36 = uieditfield(app.Tableaudesmodes, 'numeric');
            app.EditField_36.ValueDisplayFormat = '%11.5g';
            app.EditField_36.Editable = 'off';
            app.EditField_36.Tooltip = {'Mode [1,1]'};
            app.EditField_37 = uieditfield(app.Tableaudesmodes, 'numeric');
            app.EditField_37.ValueDisplayFormat = '%11.5g';
            app.EditField_37.Editable = 'off';
            app.EditField_37.Tooltip = {'Mode [1,1]'};
            app.EditField_38 = uieditfield(app.Tableaudesmodes, 'numeric');
            app.EditField_38.ValueDisplayFormat = '%11.5g';
            app.EditField_38.Editable = 'off';
            app.EditField_38.Tooltip = {'Mode [1,1]'};
            app.EditField_41 = uieditfield(app.Tableaudesmodes, 'numeric');
            app.EditField_41.ValueDisplayFormat = '%11.5g';
            app.EditField_41.Editable = 'off';
            app.EditField_41.Tooltip = {'Mode [1,1]'};
            app.EditField_42 = uieditfield(app.Tableaudesmodes, 'numeric');
            app.EditField_42.ValueDisplayFormat = '%11.5g';
            app.EditField_42.Editable = 'off';
            app.EditField_42.Tooltip = {'Mode [1,1]'};
            app.EditField_43 = uieditfield(app.Tableaudesmodes, 'numeric');
            app.EditField_43.ValueDisplayFormat = '%11.5g';
            app.EditField_43.Editable = 'off';
            app.EditField_43.Tooltip = {'Mode [1,1]'};
            app.EditField_44 = uieditfield(app.Tableaudesmodes, 'numeric');
            app.EditField_44.ValueDisplayFormat = '%11.5g';
            app.EditField_44.Editable = 'off';
            app.EditField_44.Tooltip = {'Mode [1,1]'};
            app.EditField_45 = uieditfield(app.Tableaudesmodes, 'numeric');
            app.EditField_45.ValueDisplayFormat = '%11.5g';
            app.EditField_45.Editable = 'off';
            app.EditField_45.Tooltip = {'Mode [1,1]'};
            app.EditField_46 = uieditfield(app.Tableaudesmodes, 'numeric');
            app.EditField_46.ValueDisplayFormat = '%11.5g';
            app.EditField_46.Editable = 'off';
            app.EditField_46.Tooltip = {'Mode [1,1]'};
            app.EditField_47 = uieditfield(app.Tableaudesmodes, 'numeric');
            app.EditField_47.ValueDisplayFormat = '%11.5g';
            app.EditField_47.Editable = 'off';
            app.EditField_47.Tooltip = {'Mode [1,1]'};
            app.EditField_48 = uieditfield(app.Tableaudesmodes, 'numeric');
            app.EditField_48.ValueDisplayFormat = '%11.5g';
            app.EditField_48.Editable = 'off';
            app.EditField_48.Tooltip = {'Mode [1,1]'};
            app.EditField_51 = uieditfield(app.Tableaudesmodes, 'numeric');
            app.EditField_51.ValueDisplayFormat = '%11.5g';
            app.EditField_51.Editable = 'off';
            app.EditField_51.Tooltip = {'Mode [1,1]'};
            app.EditField_52 = uieditfield(app.Tableaudesmodes, 'numeric');
            app.EditField_52.ValueDisplayFormat = '%11.5g';
            app.EditField_52.Editable = 'off';
            app.EditField_52.Tooltip = {'Mode [1,1]'};
            app.EditField_53 = uieditfield(app.Tableaudesmodes, 'numeric');
            app.EditField_53.ValueDisplayFormat = '%11.5g';
            app.EditField_53.Editable = 'off';
            app.EditField_53.Tooltip = {'Mode [1,1]'};
            app.EditField_54 = uieditfield(app.Tableaudesmodes, 'numeric');
            app.EditField_54.ValueDisplayFormat = '%11.5g';
            app.EditField_54.Editable = 'off';
            app.EditField_54.Tooltip = {'Mode [1,1]'};
            app.EditField_55 = uieditfield(app.Tableaudesmodes, 'numeric');
            app.EditField_55.ValueDisplayFormat = '%11.5g';
            app.EditField_55.Editable = 'off';
            app.EditField_55.Tooltip = {'Mode [1,1]'};
            app.EditField_56 = uieditfield(app.Tableaudesmodes, 'numeric');
            app.EditField_56.ValueDisplayFormat = '%11.5g';
            app.EditField_56.Editable = 'off';
            app.EditField_56.Tooltip = {'Mode [1,1]'};
            app.EditField_57 = uieditfield(app.Tableaudesmodes, 'numeric');
            app.EditField_57.ValueDisplayFormat = '%11.5g';
            app.EditField_57.Editable = 'off';
            app.EditField_57.Tooltip = {'Mode [1,1]'};
            app.EditField_58 = uieditfield(app.Tableaudesmodes, 'numeric');
            app.EditField_58.ValueDisplayFormat = '%11.5g';
            app.EditField_58.Editable = 'off';
            app.EditField_58.Tooltip = {'Mode [1,1]'};
            app.EditField_61 = uieditfield(app.Tableaudesmodes, 'numeric');
            app.EditField_61.ValueDisplayFormat = '%11.5g';
            app.EditField_61.Editable = 'off';
            app.EditField_61.Tooltip = {'Mode [1,1]'};
            app.EditField_62 = uieditfield(app.Tableaudesmodes, 'numeric');
            app.EditField_62.ValueDisplayFormat = '%11.5g';
            app.EditField_62.Editable = 'off';
            app.EditField_62.Tooltip = {'Mode [1,1]'};
            app.EditField_63 = uieditfield(app.Tableaudesmodes, 'numeric');
            app.EditField_63.ValueDisplayFormat = '%11.5g';
            app.EditField_63.Editable = 'off';
            app.EditField_63.Tooltip = {'Mode [1,1]'};
            app.EditField_64 = uieditfield(app.Tableaudesmodes, 'numeric');
            app.EditField_64.ValueDisplayFormat = '%11.5g';
            app.EditField_64.Editable = 'off';
            app.EditField_64.Tooltip = {'Mode [1,1]'};
            app.EditField_65 = uieditfield(app.Tableaudesmodes, 'numeric');
            app.EditField_65.ValueDisplayFormat = '%11.5g';
            app.EditField_65.Editable = 'off';
            app.EditField_65.Tooltip = {'Mode [1,1]'};
            app.EditField_66 = uieditfield(app.Tableaudesmodes, 'numeric');
            app.EditField_66.ValueDisplayFormat = '%11.5g';
            app.EditField_66.Editable = 'off';
            app.EditField_66.Tooltip = {'Mode [1,1]'};
            app.EditField_67 = uieditfield(app.Tableaudesmodes, 'numeric');
            app.EditField_67.ValueDisplayFormat = '%11.5g';
            app.EditField_67.Editable = 'off';
            app.EditField_67.Tooltip = {'Mode [1,1]'};
            app.EditField_68 = uieditfield(app.Tableaudesmodes, 'numeric');
            app.EditField_68.ValueDisplayFormat = '%11.5g';
            app.EditField_68.Editable = 'off';
            app.EditField_68.Tooltip = {'Mode [1,1]'};
            app.EditField_71 = uieditfield(app.Tableaudesmodes, 'numeric');
            app.EditField_71.ValueDisplayFormat = '%11.5g';
            app.EditField_71.Editable = 'off';
            app.EditField_71.Tooltip = {'Mode [1,1]'};
            app.EditField_72 = uieditfield(app.Tableaudesmodes, 'numeric');
            app.EditField_72.ValueDisplayFormat = '%11.5g';
            app.EditField_72.Editable = 'off';
            app.EditField_72.Tooltip = {'Mode [1,1]'};
            app.EditField_73 = uieditfield(app.Tableaudesmodes, 'numeric');
            app.EditField_73.ValueDisplayFormat = '%11.5g';
            app.EditField_73.Editable = 'off';
            app.EditField_73.Tooltip = {'Mode [1,1]'};
            app.EditField_74 = uieditfield(app.Tableaudesmodes, 'numeric');
            app.EditField_74.ValueDisplayFormat = '%11.5g';
            app.EditField_74.Editable = 'off';
            app.EditField_74.Tooltip = {'Mode [1,1]'};
            app.EditField_75 = uieditfield(app.Tableaudesmodes, 'numeric');
            app.EditField_75.ValueDisplayFormat = '%11.5g';
            app.EditField_75.Editable = 'off';
            app.EditField_75.Tooltip = {'Mode [1,1]'};
            app.EditField_76 = uieditfield(app.Tableaudesmodes, 'numeric');
            app.EditField_76.ValueDisplayFormat = '%11.5g';
            app.EditField_76.Editable = 'off';
            app.EditField_76.Tooltip = {'Mode [1,1]'};
            app.EditField_77 = uieditfield(app.Tableaudesmodes, 'numeric');
            app.EditField_77.ValueDisplayFormat = '%11.5g';
            app.EditField_77.Editable = 'off';
            app.EditField_77.Tooltip = {'Mode [1,1]'};
            app.EditField_78 = uieditfield(app.Tableaudesmodes, 'numeric');
            app.EditField_78.ValueDisplayFormat = '%11.5g';
            app.EditField_78.Editable = 'off';
            app.EditField_78.Tooltip = {'Mode [1,1]'};
            app.EditField_79 = uieditfield(app.Tableaudesmodes, 'numeric');
            app.EditField_79.Editable = 'off';
            app.EditField_79.Tooltip = {''};
            app.EditField_79.Value = 1;
            app.EditField_80 = uieditfield(app.Tableaudesmodes, 'numeric');
            app.EditField_80.Editable = 'off';
            app.EditField_80.Tooltip = {''};
            app.EditField_80.Value = 2;
            app.EditField_81 = uieditfield(app.Tableaudesmodes, 'numeric');
            app.EditField_81.Editable = 'off';
            app.EditField_81.Tooltip = {''};
            app.EditField_81.Value = 3;
            app.EditField_82 = uieditfield(app.Tableaudesmodes, 'numeric');
            app.EditField_82.Editable = 'off';
            app.EditField_82.Tooltip = {''};
            app.EditField_82.Value = 4;
            app.EditField_83 = uieditfield(app.Tableaudesmodes, 'numeric');
            app.EditField_83.Editable = 'off';
            app.EditField_83.Tooltip = {''};
            app.EditField_83.Value = 5;
            app.EditField_84 = uieditfield(app.Tableaudesmodes, 'numeric');
            app.EditField_84.Editable = 'off';
            app.EditField_84.Tooltip = {''};
            app.EditField_84.Value = 6;
            app.EditField_85 = uieditfield(app.Tableaudesmodes, 'numeric');
            app.EditField_85.Editable = 'off';
            app.EditField_85.Tooltip = {''};
            app.EditField_85.Value = 7;
            app.EditField_86 = uieditfield(app.Tableaudesmodes, 'numeric');
            app.EditField_86.Editable = 'off';
            app.EditField_86.Tooltip = {''};
            app.EditField_86.Value = 8;
            app.EditField_87 = uieditfield(app.Tableaudesmodes, 'numeric');
            app.EditField_87.Editable = 'off';
            app.EditField_87.Tooltip = {''};
            app.EditField_87.Value = 2;
            app.EditField_88 = uieditfield(app.Tableaudesmodes, 'numeric');
            app.EditField_88.Editable = 'off';
            app.EditField_88.Tooltip = {''};
            app.EditField_88.Value = 3;
            app.EditField_89 = uieditfield(app.Tableaudesmodes, 'numeric');
            app.EditField_89.Editable = 'off';
            app.EditField_89.Tooltip = {''};
            app.EditField_89.Value = 4;
            app.EditField_90 = uieditfield(app.Tableaudesmodes, 'numeric');
            app.EditField_90.Editable = 'off';
            app.EditField_90.Tooltip = {''};
            app.EditField_90.Value = 5;
            app.EditField_91 = uieditfield(app.Tableaudesmodes, 'numeric');
            app.EditField_91.Editable = 'off';
            app.EditField_91.Tooltip = {''};
            app.EditField_91.Value = 6;
            app.EditField_92 = uieditfield(app.Tableaudesmodes, 'numeric');
            app.EditField_92.Editable = 'off';
            app.EditField_92.Tooltip = {''};
            app.EditField_92.Value = 7;
            app.EditField_93 = uieditfield(app.Tableaudesmodes, 'numeric');
            app.EditField_93.Editable = 'off';
            app.EditField_93.Tooltip = {''};
            app.EditField_93.Value = 8;
            app.EditField_94 = uieditfield(app.Tableaudesmodes, 'numeric');
            app.EditField_94.Editable = 'off';
            app.EditField_94.Tooltip = {''};
            app.EditField_94.Value = 1;
            app.EditField_95 = uieditfield(app.Tableaudesmodes, 'text');
            app.EditField_95.Editable = 'off';
            app.EditField_95.Value = 'Ordre des modes en [Hz]';
            app.EditField_96 = uieditfield(app.Tableaudesmodes, 'text');
            app.EditField_96.Editable = 'off';
            app.EditField_96.Value = 'M';
            app.EditField_97 = uieditfield(app.Tableaudesmodes, 'text');
            app.EditField_97.Editable = 'off';
            app.EditField_97.Value = 'N';
            app.CalculButton = uibutton(app.Tableaudesmodes, 'push');
            app.CalculButton.ButtonPushedFcn = createCallbackFcn(app, @CalculdesdformesmodalesButtonPushed, true);
            app.CalculButton.Text = 'Calcul';
            app.EvaluationdurayonnementTab = uitab(app.TabGroup);
            app.EvaluationdurayonnementTab.Title = 'Evaluation du rayonnement';
            app.FrquencedintrtHzEditFieldLabel = uilabel(app.EvaluationdurayonnementTab);
            app.FrquencedintrtHzEditFieldLabel.Text = 'Fréquence d''intérêt [Hz]';
            app.FrquencedintrtHzEditField = uieditfield(app.EvaluationdurayonnementTab, 'numeric');
            app.FrquencedintrtHzEditField.Limits = [100 5000];
            app.FrquencedintrtHzEditField.ValueChangedFcn = createCallbackFcn(app, @FrquencedintrtHzEditFieldValueChanged, true);
            app.FrquencedintrtHzEditField.Value = 2000;
            app.UIAxes2 = uiaxes(app.EvaluationdurayonnementTab);
            title(app.UIAxes2, 'Spectre de nombre d"onde pour une plaque pour les modes M & N')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            app.Calcul_SpectreButton = uibutton(app.EvaluationdurayonnementTab, 'push');
            app.Calcul_SpectreButton.ButtonPushedFcn = createCallbackFcn(app, @Calcul_SpectreButtonPushed, true);
            app.Calcul_SpectreButton.Text = 'Calcul_Spectre';
            app.Image6 = uiimage(app.EvaluationdurayonnementTab);
            app.Image6.ImageSource = 'leg.png';
            app.FacteurderayonnementapprochTab = uitab(app.TabGroup);
            app.FacteurderayonnementapprochTab.Title = 'Facteur de rayonnement approché';
            app.UIAxes3 = uiaxes(app.FacteurderayonnementapprochTab);
            title(app.UIAxes3, 'Facteur de rayonnement approché')
            xlabel(app.UIAxes3, 'Frequency [Hz]')
            ylabel(app.UIAxes3, 'Modal efficiency')
            app.UIAxes3.XScale = 'log';
            app.UIAxes3.YScale = 'log';
            app.CalculButton_2 = uibutton(app.FacteurderayonnementapprochTab, 'push');
            app.CalculButton_2.ButtonPushedFcn = createCallbackFcn(app, @CalculdesdformesmodalesButtonPushed, true);
            app.CalculButton_2.Text = 'Calcul';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = plate_radiation_v0

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end